classdef draft < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ProductsListBoxLabel           matlab.ui.control.Label
        ProductsListBox                matlab.ui.control.ListBox
        FindProductButton              matlab.ui.control.Button
        ProductBrandEditFieldLabel     matlab.ui.control.Label
        ProductBrandEditField          matlab.ui.control.EditField
        ReportedSymptomsListBoxLabel   matlab.ui.control.Label
        ReportedSymptomsListBox        matlab.ui.control.ListBox
        FindAdverseEventsButton        matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        SEXEditFieldLabel              matlab.ui.control.Label
        SEXEditField                   matlab.ui.control.EditField
        AGEEditFieldLabel              matlab.ui.control.Label
        AGEEditField                   matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        CategoryEditFieldLabel         matlab.ui.control.Label
        CategoryEditField              matlab.ui.control.EditField
        PlotAccumulateduserdataButton  matlab.ui.control.Button
        CHECKFDAADVERSEEVENTREPORTSBEFOREYOUBUYLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        productlist % structures, which have all the data from the excel file
    end

    properties (Access = public)
        cachestruct % structures, which are extracted from the big data IOT save time of searching
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.productlist = getthedata();
             
        end

        % Callback function
        function ListBoxValueChanged(app, event)
            
            
            value = app.ProductsListBox.Value;
            
        end

        % Callback function
        function EditFieldValueChanged(app, event)
  
        end

        % Button pushed function: FindProductButton
        function FindProductButtonPushed(app, event)
            if isempty(app.ProductBrandEditField.Value)
                app.Label.Text = "Error: Please enter a product name";
            elseif strlength(app.ProductBrandEditField.Value) <= 4
                app.Label.Text = "Error: Please enter at least five characters";
            elseif (string(app.SEXEditField.Value) ~= "MALE" && string(app.SEXEditField.Value) ~= "FEMALE")
                app.Label.Text = "Error: Please enter your gender. Correct Value : MALE / FEMALE";
            elseif (isempty(app.AGEEditField.Value) || app.AGEEditField.Value <= 0)
                app.Label.Text = "Error: Please enter your age correctly";
            else
                app.Label_2.Text = "SEARCHING";
                app.Label.Text = "";
                app.cachestruct = extractbyname(app.productlist, app.ProductBrandEditField.Value); %Extract data from the original big data
                [app.ProductsListBox.Items, ~, ~] = searchbyname(app.cachestruct,app.ProductBrandEditField.Value);%Search from the extracted data, not the big data, IOT save time
                app.Label_2.Text = "COMPLETE";
            end
            
        end

        % Button pushed function: FindAdverseEventsButton
        function FindAdverseEventsButtonPushed(app, event)
            if isempty(app.ProductBrandEditField.Value)
                app.Label.Text = "Error: Please enter a product name";
            elseif strlength(app.ProductBrandEditField.Value) <= 4
                app.Label.Text = "Error: Please enter at least five characters";
            elseif (string(app.SEXEditField.Value) ~= "MALE" && string(app.SEXEditField.Value) ~= "FEMALE")
                app.Label.Text = "Error: Please enter your gender. Correct Value : MALE / FEMALE";
            elseif (isempty(app.AGEEditField.Value) || app.AGEEditField.Value <= 0)
                app.Label.Text = "Error: Please enter your age correctly";
            elseif isempty(app.ProductsListBox.Value)
            else
            [~,app.ReportedSymptomsListBox.Items,app.CategoryEditField.Value] = searchbyname(app.cachestruct,app.ProductsListBox.Value);
            write2file(app.SEXEditField.Value, app.AGEEditField.Value, app.CategoryEditField.Value, app.ProductsListBox.Value); %Write user searched-data to a file.
            saveplotdatam(app.SEXEditField.Value, app.AGEEditField.Value, app.CategoryEditField.Value); %Write user searched-data(for plotting)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clear functions %IOT clear persistent structures in extractbyname function
        end

        % Button pushed function: PlotAccumulateduserdataButton
        function PlotAccumulateduserdataButtonPushed(app, event)
            plotuserdata();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProductsListBoxLabel = uilabel(app.UIFigure);
            app.ProductsListBoxLabel.Text = 'Products';
            app.ProductsListBox = uilistbox(app.UIFigure);
            app.ProductsListBox.Items = {};
            app.ProductsListBox.Value = {};
            app.FindProductButton = uibutton(app.UIFigure, 'push');
            app.FindProductButton.ButtonPushedFcn = createCallbackFcn(app, @FindProductButtonPushed, true);
            app.FindProductButton.Text = 'Find Product';
            app.ProductBrandEditFieldLabel = uilabel(app.UIFigure);
            app.ProductBrandEditFieldLabel.Text = 'Product / Brand';
            app.ProductBrandEditField = uieditfield(app.UIFigure, 'text');
            app.ReportedSymptomsListBoxLabel = uilabel(app.UIFigure);
            app.ReportedSymptomsListBoxLabel.Text = 'Reported Symptoms';
            app.ReportedSymptomsListBox = uilistbox(app.UIFigure);
            app.ReportedSymptomsListBox.Items = {};
            app.ReportedSymptomsListBox.Value = {};
            app.FindAdverseEventsButton = uibutton(app.UIFigure, 'push');
            app.FindAdverseEventsButton.ButtonPushedFcn = createCallbackFcn(app, @FindAdverseEventsButtonPushed, true);
            app.FindAdverseEventsButton.Text = 'Find Adverse Events';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.SEXEditFieldLabel = uilabel(app.UIFigure);
            app.SEXEditFieldLabel.Text = 'SEX';
            app.SEXEditField = uieditfield(app.UIFigure, 'text');
            app.SEXEditField.Value = 'MALE / FEMALE';
            app.AGEEditFieldLabel = uilabel(app.UIFigure);
            app.AGEEditFieldLabel.Text = 'AGE';
            app.AGEEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.CategoryEditFieldLabel = uilabel(app.UIFigure);
            app.CategoryEditFieldLabel.Text = 'Category';
            app.CategoryEditField = uieditfield(app.UIFigure, 'text');
            app.CategoryEditField.Editable = 'off';
            app.PlotAccumulateduserdataButton = uibutton(app.UIFigure, 'push');
            app.PlotAccumulateduserdataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAccumulateduserdataButtonPushed, true);
            app.PlotAccumulateduserdataButton.Text = 'Plot Accumulated user data';
            app.CHECKFDAADVERSEEVENTREPORTSBEFOREYOUBUYLabel = uilabel(app.UIFigure);
            app.CHECKFDAADVERSEEVENTREPORTSBEFOREYOUBUYLabel.Text = 'CHECK FDA ADVERSE EVENT REPORTS BEFORE YOU BUY';
        end
    end

    methods (Access = public)

        % Construct app
        function app = draft

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end